classdef RoiImagingModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoiImagingModuleUIFigure     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveConfigMenu               matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        AWGSettingsMenu              matlab.ui.container.Menu
        ScannerSettingsMenu          matlab.ui.container.Menu
        AddonsMenu                   matlab.ui.container.Menu
        AWGControlMenu               matlab.ui.container.Menu
        ROIImagingSimulationMenu     matlab.ui.container.Menu
        CustomDrawMenu_2             matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        SimulationToggleTool         matlab.ui.container.toolbar.ToggleTool
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SettingsPanel                matlab.ui.container.Panel
        ConfigurationFileEditField   matlab.ui.control.EditField
        ConfigurationEditFieldLabel  matlab.ui.control.Label
        AdvancedSettingsOpenButton   matlab.ui.control.Button
        ConfigFileSelectButton       matlab.ui.control.Button
        isConnectedLabel             matlab.ui.control.Label
        AWGStatusLabel               matlab.ui.control.Label
        AwgConnectButton             matlab.ui.control.Button
        ROIImagingPanel              matlab.ui.container.Panel
        CheckBox                     matlab.ui.control.CheckBox
        LoadMaskButton               matlab.ui.control.Button
        AbortButton                  matlab.ui.control.Button
        ROIImagingButton             matlab.ui.control.Button
        ManualcorrectionPanel        matlab.ui.container.Panel
        BrushSizeEditField           matlab.ui.control.NumericEditField
        BrushSizeEditFieldLabel      matlab.ui.control.Label
        MaskonCheckBox               matlab.ui.control.CheckBox
        NeuronSegmentationPanel      matlab.ui.container.Panel
        RunModelButton               matlab.ui.control.Button
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ModelsDropDown               matlab.ui.control.DropDown
        ModelsDropDownLabel          matlab.ui.control.Label
        StructureImagingButtonGroup  matlab.ui.container.ButtonGroup
        AWGOutputButton              matlab.ui.control.Button
        LoadImageButton              matlab.ui.control.Button
        oneOnNineOffButton           matlab.ui.control.RadioButton
        KeeponButton                 matlab.ui.control.RadioButton
        RightPanel                   matlab.ui.container.Panel
        ChannelDropDown              matlab.ui.control.DropDown
        RoiProportionLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Sub APP
    properties (Access = public)
        AwgSettingsApp = 0;% sub-app for awg settings
        ScannerSettingsApp = 0; % sub-app for scanner settings
        AwgControlApp;
        SimulationApp;
        awgDevice; % AWG Device Object
        roiMask % Description
        imgAvgCh1;% Description
        imgAvgCh2;
    end
    
    properties (Access = private)
        folder; % app folder
        lastStructureImagepath = '';  % save last selected path of structure
        lastRoiMaskPath= ''; % save last selected path of roi mask
        lastConfigPath = ''; % save last selected path of config file
    end
    
    % AWG Settings,Constant =true 让外部可以直接访问
    properties (Access = public)

        defaultConfig = struct();
        waveformConfig = struct();
        scannerConfig = struct();
    end


    methods (Access = private)
        
        function create_51on(app)
            imageSize = app.scannerConfig.imageSize;

            % generate ROI pulse for each frame
            % 循环10次，创建waveformHandlesArray
            n = 10;
            waveformHandlesArray = cell(1,n);
            
            colNum = floor(imageSize/n);
            for i=1:n
                % 创建一个黑色的图像矩阵
                temp_mask = repmat(app.defaultConfig.pulseOff,imageSize, imageSize);

                % 计算本次要填充的列
                start = (i-1)*colNum+1;

                % 将这些列填充为白色
                if i == 10
                    % 如果是最后一列，则最后一次填充剩余的所有列
                    temp_mask(:, start:end) = app.defaultConfig.pulseOn;
                else
                    % 如果是前9次，则一次填充colNum列
                    temp_mask(:, start:start+colNum-1) = app.defaultConfig.pulseOn;
                end
                

                framePulse = create_frame_roi_pulse(app,temp_mask);

                % create waveform
                waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

                % add to waveformHandlesArray
                waveformHandlesArray{i} = waveformHandle;
            end
            waveformHandlesArray = cell2mat(waveformHandlesArray); % matlab 循环用cell 存储，然后再转化为mat矩阵

            sequenceLength = n; % seq 数
            loopCountsArray = ones(1,n); % 设置每个waveform的输出
            waveformSize = numel(framePulse);
            sampleCountsArray = repmat(waveformSize,1,n); % 每个waveform长度
            markerLocationArray = repmat(-1,1,n); % 是否输出markerLocation

            configureOutputMode(app.awgDevice,"OUTPUT_SEQ");
            [~, sequenceHandle] = createAdvancedArbSequence(app.awgDevice, sequenceLength, waveformHandlesArray, loopCountsArray, sampleCountsArray, markerLocationArray); % 创建sequenceHandle

            % generate Arb waveform
            awg.create_arb_sequence(app.awgDevice,sequenceHandle,app.waveformConfig);
        end
        
        function create_1on9off(app)
            imageSize = app.scannerConfig.imageSize;

            % generate ROI pulse for each frame
            % 循环10次，创建waveformHandlesArray
            n = 10;
            waveformHandlesArray = cell(1,n);
            for i=1:n
                % 创建一个黑色的图像矩阵
                temp_mask = repmat(app.defaultConfig.pulseOff,imageSize, imageSize);

                % 计算本次要填充的列
                fill_col = i : n : imageSize;

                % 将这些列填充为白色
                temp_mask(:, fill_col) = app.defaultConfig.pulseOn;
                framePulse = create_frame_roi_pulse(app,temp_mask);

                % create waveform
                waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

                % add to waveformHandlesArray
                waveformHandlesArray{i} = waveformHandle;
            end

            waveformHandlesArray = cell2mat(waveformHandlesArray); % matlab 循环用cell 存储，然后再转化为mat矩阵

            sequenceLength = n; % seq 数
            loopCountsArray = ones(1,n); % 设置每个waveform的输出
            waveformSize = numel(framePulse);
            sampleCountsArray = repmat(waveformSize,1,n); % 每个waveform长度
            markerLocationArray = repmat(-1,1,n); % 是否输出markerLocation

            configureOutputMode(app.awgDevice,"OUTPUT_SEQ");
            [~, sequenceHandle] = createAdvancedArbSequence(app.awgDevice, sequenceLength, waveformHandlesArray, loopCountsArray, sampleCountsArray, markerLocationArray); % 创建sequenceHandle

            % generate Arb waveform
            awg.create_arb_sequence(app.awgDevice,sequenceHandle,app.waveformConfig);
        end

        function framePulse = create_frame_roi_pulse(app,roiMask)
            
            % Read parameters
            pulsePerPixel = app.scannerConfig.pulsePerPixel;
            scanBackLeftPixelTwice = app.scannerConfig.scanBackLeftPixelTwice * pulsePerPixel; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            scanBackRightPixelTwice = app.scannerConfig.scanBackRightPixelTwice * pulsePerPixel; % unit: pixel
            imageSize = app.scannerConfig.imageSize;
            scanWait = app.scannerConfig.scanWait;
            
            
            % Generate ROI mask
            roiMaskPulse = repelem(roiMask, 1, pulsePerPixel); % Each pixel has several pulses, so the signal needs to be repeated
            roiMaskPulse(2:2:end, :) = fliplr(roiMaskPulse(2:2:end, :)); % Because of bidirectional scanning, the laser is scanned from left to right in the first line, and the second line is directly scanned from right, so even rows need to be mirrored
            
            % Add scan right to odd rows
            oddLineSignal = [roiMaskPulse(1:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackRightPixelTwice)];
            
            % Add scan left to even rows
            evenLineSignal = [roiMaskPulse(2:2:end, :) repmat(app.defaultConfig.pulseOff, imageSize / 2, scanBackLeftPixelTwice)];
            
            % Then the odd row matrix and the even row matrix are pasted together in sequence and reduced to 1*n (considering the impact of changing the length of the array dynamically in the loop on performance, so use this method to optimize)
            signalPulse = reshape([oddLineSignal evenLineSignal]', 1, []);
            
            % Compose the final framePusle
            waitPulse = repmat(app.defaultConfig.pulseOff, 1, scanWait); % Add wait time in front
            scanleftFirstPulse = repmat(app.defaultConfig.pulseOff, 1, round(scanBackLeftPixelTwice / 2));
            framePulse = [waitPulse, scanleftFirstPulse, signalPulse(1:end - round(scanBackLeftPixelTwice / 2))]; % The first scanleft is missing, the last scanleft is extra
            
            % Actually, do not need to consider Flyback, but the length of the waveform must be an integer multiple of 4, so it needs to be supplemented
            if mod(length(framePulse), 4) ~= 0
                extraNum = 4 - mod(length(framePulse), 4);
                framePulse = [framePulse, repmat(app.defaultConfig.pulseOff, 1, extraNum)];
            end
        end
        
        function load_config(app,path)
            % read file
            fid = fopen(path, 'r');
            jsonStr = fscanf(fid, '%c');
            fclose(fid);
            % json to struct
            userConfig  = jsondecode(jsonStr);
            % assignment value
            app.defaultConfig = userConfig.defaultConfig;
            app.waveformConfig = userConfig.waveformConfig;
            app.scannerConfig = userConfig.scannerConfig;
        end
        
        function init_default(app)
            % get app folder
            fullpath = mfilename('fullpath');
            [path,~]=fileparts(fullpath);
            app.folder = path;
            % defaultConfig
            app.defaultConfig.pulseOn = 0;
            app.defaultConfig.pulseOff = 1;
            app.defaultConfig.configPath = './config/';
            app.defaultConfig.roimaskPath = './roi_mask/';
            % waveformConfig
            app.waveformConfig.resourceID = "Dev1"; % AWG Var: resourceID
            app.waveformConfig.gain = 3.3;
            app.waveformConfig.offset = 0;
            app.waveformConfig.sampleRate = 1.0007e6; % 1.0007 MHz
            app.waveformConfig.delayTime = 0;
            app.waveformConfig.mode = 'Stepped'; % Stepped
            app.waveformConfig.clockSource = 'ClkIn'; % 'OnboardClock' or 'ClkIn'
            app.waveformConfig.triggerOn = true; % true or false
            app.waveformConfig.triggerPort = 'PFI0'; % PFI0 or PFI1
            app.waveformConfig.exportSignalOn = true; % true or false
            app.waveformConfig.exportSignalType = 'DataMarker'; % Marker DataMarker
            app.waveformConfig.exportSignalPort = 'PFI1'; % PFI0 or PFI1

            % scannerConfig
            app.scannerConfig.imageSize = 512;
            app.scannerConfig.pulsePerPixel = 1;
            app.scannerConfig.scanWait = 56;
            app.scannerConfig.scanBackLeftPixelTwice = 40; % unit: pixel，scanleft和 scan right 也受到 pulsePerPixel 的影响
            app.scannerConfig.scanBackRightPixelTwice = 40; % unit: pixel
        end
        
        function process_structure_image(app,filename,path)
                % 分割tif为两个通道
                fullpath = fullfile(path, filename);
                [imgStackCh1,imgStackCh2] = utils.tiff_split(fullpath);

                % 每十张组合成一张图
                imgStackCombineCh1 =  utils.tiff_combine(imgStackCh1);
                imgStackCombineCh2 =  utils.tiff_combine(imgStackCh2);

                % 保存为raw tif
                [~, name, ext] = fileparts(filename);
                % 创建aes_processed文件夹
                outputPath = fullfile(path,'AES_processed'); % TODO：这个也作为配置文件？
                if ~exist(outputPath, 'dir')
                    mkdir(outputPath)
                end


                utils.tiff_save(fullfile(outputPath, [name,'_ch1',ext]),imgStackCombineCh1);
                utils.tiff_save(fullfile(outputPath, [name,'_ch2',ext]),imgStackCombineCh2);

                % 保存为平均图
                app.imgAvgCh1 = utils.tiff_save_avg(fullfile(outputPath, [name,'_ch1_AVG',ext]),imgStackCombineCh1);
                app.imgAvgCh2 = utils.tiff_save_avg(fullfile(outputPath, [name,'_ch2_AVG',ext]),imgStackCombineCh2);

                % 加载imgAvgCh1的图像
                app.ChannelDropDown.Enable = "on";
                tempImg=mat2gray(app.imgAvgCh1); %将im归一化到[0 1]区间内，且im中的最大值和最小值赋值为1和0
                tempImg=im2uint8(tempImg); %将im扩充到[0 255]
                app.Image.ImageSource = repmat(tempImg,1,1,3);
            
        end
        
        function load_roi_mask(app,path,filename)
            % 读取自定义的图像数据，由于是Active low logic，所以需要把1（白）变为0,0（黑）变为1
            app.roiMask = table2array(readtable([path,filename]));
            assignin('base','mask',app.roiMask);
            % load mask
            app.Image.ImageSource = repmat(app.roiMask,1,1,3);
            % caculate ROI ratio
            ratio = round(length(find(app.roiMask==1))/numel(app.roiMask),4);
            app.RoiProportionLabel.Text = ['ROI Ratio: ','',num2str(ratio)];
            % active low logic
            app.roiMask = utils.active_low_logic(app.roiMask);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RoiImagingModuleUIFigure.Position = [40 100 899 661];
            
            % init parameter
            app.init_default();
            % init config settings

            default_json = fullfile(app.folder,app.defaultConfig.configPath,'default.json');
            if exist(default_json,'file') ~= 0
                % if default.json exist
                app.load_config(default_json);
                app.ConfigurationFileEditField.Value = 'default.json';
            end
 
        end

        % Button pushed function: ConfigFileSelectButton
        function ConfigFileSelectButtonPushed(app, event)
            % select file
            if isempty(app.lastConfigPath)
                % 考虑当前文件夹可能不是app文件夹
                configPath = fullfile(app.folder,app.defaultConfig.configPath);
                [filename,path]= utils.select_file({'*.json'},configPath);
            else
               [filename,path]= utils.select_file({'*.json'}, app.lastConfigPath);
            end
            
            if filename ~= 0 
                fullpath = [path,filename];
                
                % save path for next click
                app.lastConfigPath = path;

                % load conig
                app.load_config(fullpath)
                
                % show config file in GUI 
                app.ConfigurationFileEditField.Value = filename;

                % log in command line
                disp(['Loaded Config：',fullpath]);


                % if subapp is open
                if app.AwgSettingsApp ~= 0
                    app.AwgSettingsApp.variableInit();
                end
                if app.ScannerSettingsApp ~= 0
                    app.ScannerSettingsApp.variableInit();
                end
            end


        end

        % Button pushed function: AdvancedSettingsOpenButton
        function AdvancedSettingsOpenButtonPushed(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Close request function: RoiImagingModuleUIFigure
        function RoiImagingModuleUIFigureCloseRequest(app, event)
            % when exit MainApp
            % disconnect awg device
            if ~isempty(app.awgDevice)
                awg.disconnect(app.awgDevice);
            end
            
            % close subapp
            if app.AwgSettingsApp ~= 0 
                delete(app.AwgSettingsApp)
            end
            if app.ScannerSettingsApp ~= 0
                delete(app.ScannerSettingsApp)
            end
            
            % close MainApp
            delete(app)
        end

        % Button pushed function: AwgConnectButton
        function AwgConnectButtonPushed(app, event)
            if app.isConnectedLabel.Text == "Disconnected"
                
                % If not connected, click to connect
                simulateState = app.SimulationToggleTool.State;
                
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Connecting AWG',...
                    'Indeterminate','on');
                drawnow

                % your code
                [app.awgDevice,status] = awg.connect(app.waveformConfig.resourceID,simulateState);

                % close the dialog box
                close(d);

                if status
                    app.isConnectedLabel.Text = 'Connected';
                    app.AwgConnectButton.Text = 'Disconnect';
                end
            else
                % % If connected, click to disconnect
                awg.disconnect(app.awgDevice);
                app.isConnectedLabel.Text = 'Disconnected';
                app.AwgConnectButton.Text = 'Connect';
            end
        end

        % Button pushed function: AWGOutputButton
        function AWGOutputButtonPushed(app, event)
            reset(app.awgDevice);
            
            % 如果是keepon
            if app.KeeponButton.Value
                % create Arb waveform
                waveformDataArray = repmat(app.defaultConfig.pulseOn,1,20);
                waveformHandle = awg.create_waveform_handle(app.awgDevice,waveformDataArray);

                % generate Arb waveform

                awg.create_arb_waveform(app.awgDevice,waveformHandle,app.waveformConfig);
            
            % 如果是选择0.1MHz成像
            else
                app.create_51on();
                %app.create_1on9off();
            end

        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            abortGeneration(app.awgDevice);
        end

        % Button pushed function: ROIImagingButton
        function ROIImagingButtonPushed(app, event)
            % reset AWG device for new waveform
            reset(app.awgDevice);

            % generate ROI pulse for each frame
            framePulse = app.create_frame_roi_pulse(app.roiMask);

            % create waveform
            waveformHandle = awg.create_waveform_handle(app.awgDevice,framePulse);

            % generate Arb waveform
            awg.create_arb_waveform(app.awgDevice,waveformHandle,app.waveformConfig);

        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                app.LoadMaskButton.Enable = "on";
            else
                app.LoadMaskButton.Enable = "off";
            end
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            
            % 读取tif
            if isempty(app.lastRoiMaskPath)
                % 考虑当前文件夹可能不是app文件夹
                roimaskPath = fullfile(app.folder,app.defaultConfig.roimaskPath);
                [filename,path] = utils.select_file({'*.csv';'*.txt'},roimaskPath);
            else
                
                [filename,path] = utils.select_file({'*.csv';'*.txt'},app.lastRoiMaskPath);
            end
            if filename ~= 0 % 如果不选择文件返回为0
                % save path for next click
                app.lastRoiMaskPath = path;
                
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Loading ROI Mask',...
                    'Indeterminate','on');
                drawnow

                % your code
                app.load_roi_mask(path,filename);

                % close the dialog box
                close(d);


            end
            
            % update ui
            app.ChannelDropDown.Enable = "off";
        end

        % Menu selected function: AWGSettingsMenu
        function AWGSettingsMenuSelected(app, event)
            app.AwgSettingsApp = subapps.AwgSettings(app);
        end

        % Menu selected function: ScannerSettingsMenu
        function ScannerSettingsMenuSelected(app, event)
            app.ScannerSettingsApp = subapps.ScannerSettings(app);
        end

        % Menu selected function: AWGControlMenu
        function AWGControlMenuSelected(app, event)
            app.AwgControlApp = addons.AWG_Control_2022b();
            
        end

        % Menu selected function: ROIImagingSimulationMenu
        function ROIImagingSimulationMenuSelected(app, event)
            app.SimulationApp = addons.simulation_veritical_stripe();
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            
            userConfig.defaultConfig = app.defaultConfig;
            userConfig.waveformConfig = app.waveformConfig;
            userConfig.scannerConfig = app.scannerConfig;


            if isempty(app.lastConfigPath)
                [filename,path] =  utils.save_file({'*.json'},app.defaultConfig.configPath);
            else
               [filename,path] =  utils.save_file({'*.json'}, app.lastConfigPath);
            end

            if ischar(filename) && ischar(path)
                fullpath = fullfile(path, filename);
                % save for next click
                app.lastConfigPath = path;
                % save to json
                jsonStr = jsonencode(userConfig,'PrettyPrint',true);
                fid = fopen(fullpath, 'w');
                fprintf(fid, jsonStr);
                fclose(fid);
                % log
                disp(['Config Saved to File: ',fullpath]);

                % change config file in GUI
                app.ConfigurationFileEditField.Value = filename;
            end

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            
            % 读取tif
            if isempty(app.lastStructureImagepath)
                [filename,path]= utils.select_file({'*.tif'});
            else
                [filename,path]= utils.select_file({'*.tif'},app.lastStructureImagepath);
                
            end

            if filename ~= 0 
                % create progress dialog
                d = uiprogressdlg(app.RoiImagingModuleUIFigure,'Title','Loading Image',...
                    'Indeterminate','on');
                drawnow

                % your code
                app.process_structure_image(filename,path);
                
                % close the dialog box
                close(d);

                % 保存选择的路径，方便下次再点击
                app.lastStructureImagepath = path;

            end
        end

        % Button down function: RightPanel
        function RightPanelButtonDown(app, event)

        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            switch value
                case 'CH1'
                    img = app.imgAvgCh1;
                case 'CH2'
                    img = app.imgAvgCh2;
            end
            tempImg=mat2gray(img); %将im归一化到[0 1]区间内，且im中的最大值和最小值赋值为1和0
            tempImg=im2uint8(tempImg); %将im扩充到[0 255]
            app.Image.ImageSource = repmat(tempImg,1,1,3);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RoiImagingModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {654, 654};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RoiImagingModuleUIFigure = uifigure('Visible', 'off');
            app.RoiImagingModuleUIFigure.Name = 'ROI Imaging Module';
            app.RoiImagingModuleUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoiImagingModuleUIFigureCloseRequest, true);
            app.RoiImagingModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = ' File ';
            app.SaveConfigMenu = uimenu(app.FileMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.SettingsMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.SettingsMenu.Text = ' Settings ';
            app.AWGSettingsMenu = uimenu(app.SettingsMenu);
            app.AWGSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGSettingsMenuSelected, true);
            app.AWGSettingsMenu.Text = 'AWG Settings';
            app.ScannerSettingsMenu = uimenu(app.SettingsMenu);
            app.ScannerSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ScannerSettingsMenuSelected, true);
            app.ScannerSettingsMenu.Text = 'Scanner Settings';
            app.AddonsMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.AddonsMenu.Text = ' Add-ons ';
            app.AWGControlMenu = uimenu(app.AddonsMenu);
            app.AWGControlMenu.MenuSelectedFcn = createCallbackFcn(app, @AWGControlMenuSelected, true);
            app.AWGControlMenu.Text = 'AWG Control';
            app.ROIImagingSimulationMenu = uimenu(app.AddonsMenu);
            app.ROIImagingSimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIImagingSimulationMenuSelected, true);
            app.ROIImagingSimulationMenu.Text = 'ROI Imaging Simulation';
            app.CustomDrawMenu_2 = uimenu(app.AddonsMenu);
            app.CustomDrawMenu_2.Text = 'Custom Draw';
            app.HelpMenu = uimenu(app.RoiImagingModuleUIFigure);
            app.HelpMenu.Text = ' Help ';
            app.Toolbar = uitoolbar(app.RoiImagingModuleUIFigure);
            app.SimulationToggleTool = uitoggletool(app.Toolbar);
            app.SimulationToggleTool.Icon = fullfile(pathToMLAPP, 'src', 'simulation.svg');
            app.GridLayout = uigridlayout(app.RoiImagingModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StructureImagingButtonGroup = uibuttongroup(app.LeftPanel);
            app.StructureImagingButtonGroup.Title = '1. Structure Imaging';
            app.KeeponButton = uiradiobutton(app.StructureImagingButtonGroup);
            app.KeeponButton.Text = 'Keep on';
            app.oneOnNineOffButton = uiradiobutton(app.StructureImagingButtonGroup);
            app.oneOnNineOffButton.Text = '0.1MHz';
            app.oneOnNineOffButton.Value = true;
            app.LoadImageButton = uibutton(app.StructureImagingButtonGroup, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load  Image';
            app.AWGOutputButton = uibutton(app.StructureImagingButtonGroup, 'push');
            app.AWGOutputButton.ButtonPushedFcn = createCallbackFcn(app, @AWGOutputButtonPushed, true);
            app.AWGOutputButton.Text = 'AWG Output';
            app.NeuronSegmentationPanel = uipanel(app.LeftPanel);
            app.NeuronSegmentationPanel.Title = '2. Neuron Segmentation';
            app.ModelsDropDownLabel = uilabel(app.NeuronSegmentationPanel);
            app.ModelsDropDownLabel.Text = 'Models';
            app.ModelsDropDown = uidropdown(app.NeuronSegmentationPanel);
            app.ModelsDropDown.Items = {'Model 1', 'Model 2', 'Model 3', 'Model 4'};
            app.ModelsDropDown.Value = 'Model 1';
            app.ThresholdEditFieldLabel = uilabel(app.NeuronSegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.NeuronSegmentationPanel, 'numeric');
            app.ThresholdEditField.Value = 0.2;
            app.RunModelButton = uibutton(app.NeuronSegmentationPanel, 'push');
            app.RunModelButton.Text = 'Run Model';
            app.ManualcorrectionPanel = uipanel(app.LeftPanel);
            app.ManualcorrectionPanel.Title = '3. Manual correction';
            app.MaskonCheckBox = uicheckbox(app.ManualcorrectionPanel);
            app.MaskonCheckBox.Text = '  Mask on';
            app.MaskonCheckBox.Value = true;
            app.BrushSizeEditFieldLabel = uilabel(app.ManualcorrectionPanel);
            app.BrushSizeEditFieldLabel.Text = 'Brush Size';
            app.BrushSizeEditField = uieditfield(app.ManualcorrectionPanel, 'numeric');
            app.BrushSizeEditField.Value = 3;
            app.ROIImagingPanel = uipanel(app.LeftPanel);
            app.ROIImagingPanel.Title = '4. ROI Imaging';
            app.ROIImagingButton = uibutton(app.ROIImagingPanel, 'push');
            app.ROIImagingButton.ButtonPushedFcn = createCallbackFcn(app, @ROIImagingButtonPushed, true);
            app.ROIImagingButton.Text = 'ROI Imaging';
            app.AbortButton = uibutton(app.ROIImagingPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.LoadMaskButton = uibutton(app.ROIImagingPanel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Enable = 'off';
            app.LoadMaskButton.Text = 'Load  Mask';
            app.CheckBox = uicheckbox(app.ROIImagingPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.AwgConnectButton = uibutton(app.SettingsPanel, 'push');
            app.AwgConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AwgConnectButtonPushed, true);
            app.AwgConnectButton.Text = 'Connect';
            app.AWGStatusLabel = uilabel(app.SettingsPanel);
            app.AWGStatusLabel.Text = 'AWG Status';
            app.isConnectedLabel = uilabel(app.SettingsPanel);
            app.isConnectedLabel.Text = 'Disconnected';
            app.ConfigFileSelectButton = uibutton(app.SettingsPanel, 'push');
            app.ConfigFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigFileSelectButtonPushed, true);
            app.ConfigFileSelectButton.Text = '...';
            app.AdvancedSettingsOpenButton = uibutton(app.SettingsPanel, 'push');
            app.AdvancedSettingsOpenButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSettingsOpenButtonPushed, true);
            app.AdvancedSettingsOpenButton.Text = 'Advanced';
            app.ConfigurationEditFieldLabel = uilabel(app.SettingsPanel);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationFileEditField = uieditfield(app.SettingsPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @RightPanelButtonDown, true);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', '示意细胞分割.png');
            app.RoiProportionLabel = uilabel(app.RightPanel);
            app.RoiProportionLabel.Text = 'ROI Ratio';
            app.ChannelDropDown = uidropdown(app.RightPanel);
            app.ChannelDropDown.Items = {'CH1', 'CH2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'CH1';
            % Show the figure after all components are created
            app.RoiImagingModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoiImagingModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoiImagingModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoiImagingModuleUIFigure)
        end
    end
end